classdef Gratings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GratingsUIFigure                matlab.ui.Figure
        ActivatestimulationwindowPanel  matlab.ui.container.Panel
        StimulationWindowSwitch         matlab.ui.control.RockerSwitch
        WindowLamp                      matlab.ui.control.Lamp
        SkipCheckBox                    matlab.ui.control.CheckBox
        SelectscreenLabel               matlab.ui.control.Label
        MonitorDropDown                 matlab.ui.control.DropDown
        ConfigurationPanel              matlab.ui.container.Panel
        DirectionsDropDownLabel         matlab.ui.control.Label
        DirectionsDropDown              matlab.ui.control.DropDown
        RandomCheckBox                  matlab.ui.control.CheckBox
        GratingssizepixelsLabel         matlab.ui.control.Label
        SizeEditField                   matlab.ui.control.NumericEditField
        FrequencyHzEditFieldLabel       matlab.ui.control.Label
        FrequencyHzEditField            matlab.ui.control.NumericEditField
        SinusoidalCheckBox              matlab.ui.control.CheckBox
        OutputvoltageDropDownLabel      matlab.ui.control.Label
        OutputDropDown                  matlab.ui.control.DropDown
        DurationPanel                   matlab.ui.container.Panel
        StimulussLabel                  matlab.ui.control.Label
        StimulusEditField               matlab.ui.control.NumericEditField
        GraysLabel                      matlab.ui.control.Label
        GrayEditField                   matlab.ui.control.NumericEditField
        RepetitionsEditFieldLabel       matlab.ui.control.Label
        RepetitionsEditField            matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        GrayRndSpinner                  matlab.ui.control.Spinner
        StimuliPanel                    matlab.ui.container.Panel
        DirectionsLabel                 matlab.ui.control.Label
        TimeLabel                       matlab.ui.control.Label
        PreviewImage                    matlab.ui.control.Image
        RunButton                       matlab.ui.control.StateButton
        DirectionLabel                  matlab.ui.control.Label
        TrialLabel                      matlab.ui.control.Label
    end

    
    properties (Access = public)
        skip_test = 0;
        screen_number = 0;
        window          
        half_width_w = 0;
        half_height_w = 0;
        gray
        increment
        stim_degrees = [180 135 90 45 0 315 270 225];
        real_degrees = [0 45 90 135 180 225 270 315];
        num_stim = 8;
        grating_pixels = 200;
        stim_duration = 2;
        gray_duration = 3;
        gray_rnd = 0;
        repetitions = 10;
        cycles_per_second = 5;
        rand_stim = true;
        sinusoidal = true;
        output_stims = false;
        ni
        cancel = false;
    end
    
    methods (Access = private)
        
        function ComputeFinalTime(app)
            total = (app.stim_duration+app.gray_duration)*app.num_stim*app.repetitions;
            app.TimeLabel.Text = sprintf('Total time:\n %d s = %.1f min',total,total/60);
        end
        
        function UpdatePreview(app)
            f = app.grating_pixels/(2*pi);
            angle = 0;
            phase = 2*pi;
            
            % generate grating frame
            [x,y] = meshgrid(-app.half_width_w:app.half_width_w,-app.half_height_w:app.half_height_w);
            a = cos(angle)/f;
            b = sin(angle)/f;
            
            % get sinusoidal or square gratings
            if app.sinusoidal
                m = sin(a*x+b*y+phase);
            else
                m = sign(sin(a*x+b*y+phase));
            end
            
            app.PreviewImage.ImageSource = repmat(mat2gray(round(app.gray+app.increment*m)),1,1,3);
        end
    
        function GetMonitors(app)
            % Get the list of screens and choose the one with the highest screen number
            screens = Screen('Screens');
            app.screen_number = max(screens);
            
            if app.screen_number<=1
                msgbox('This app requires at least 2 monitors: one for stimulating and other for controling.','Error','error')
            else
                app.MonitorDropDown.Items = cellstr(num2str((1:max(screens))'));
                app.MonitorDropDown.Value = num2str(app.screen_number);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            GetMonitors(app)
            StimulationWindowSwitchValueChanged(app,[])        
            ComputeFinalTime(app)
            if app.half_width_w
                UpdatePreview(app)
            end
        end

        % Value changed function: RunButton
        function RunButtonPushed(app, event)
            if app.RunButton.Value
                % change text
                app.RunButton.Text = 'Stop';
                
                % Use realtime priority for better timing precision:
                priorityLevel = MaxPriority(app.window);
                Priority(priorityLevel);
                
                % Run the movie animation for a fixed period
                frame_rate = Screen('FrameRate',app.screen_number);
                if frame_rate == 0
                    frame_rate = 60;
                end
            
                % Compute each frame of the movie
                f = app.grating_pixels/(2*pi);
                num_frames = round(frame_rate/app.cycles_per_second);
                
                % Start the stimuli generation
                stimSequence = [];
                app.cancel = false;
                nTrials = 1;
                tic
                for k = 1:app.repetitions
                    
                    % Do a random permutation of the stimuli
                    if app.rand_stim
                        stimOutput = randperm(app.num_stim);
                    else
                        stimOutput = 1:app.num_stim;
                    end
                    
                    stimSequence = [stimSequence stimOutput];
                    for j = 1:app.num_stim
                        if app.cancel
                            disp('Stimulation stopped')
                            app.RunButton.Text = 'Run';
                            return;
                        end
                        
                        % generate specific angle grating
                        angle = app.stim_degrees(stimOutput(j))*pi/180;
%                         rand_x = randi([-400 400]);
%                         rand_y = randi([-300 -0]);
                        
                        tex = zeros(1,num_frames);
                        for i = 1:num_frames
                            phase = (i/num_frames)*2*pi;
                            
                            % generate grating frame
                            [x,y] = meshgrid(-app.half_width_w:app.half_width_w,-app.half_height_w:app.half_height_w);
                            a = cos(angle)/f;
                            b = sin(angle)/f;
                            
                            % get sinusoidal or square gratings
                            if app.sinusoidal
                                m = sin(a*x+b*y+phase);
                            else
                                m = sign(sin(a*x+b*y+phase));
                            end
                            if i == 1
                                image = repmat(mat2gray(round(app.gray+app.increment*m)),1,1,3);
                            end
                            tex(i) = Screen('MakeTexture', app.window, round(app.gray+app.increment*m));
                        end
                        
                        % Convert movieDuration in seconds to duration in frames to draw:
                        movie_duration_frames = round(app.stim_duration * frame_rate);
                        movie_frame_indices = mod(0:(movie_duration_frames-1), num_frames) + 1;
            
                        % Animation loop
                        delay = app.gray_duration+rand*app.gray_rnd*2-app.gray_rnd;
                        t = toc;
                        if t<delay
                            app.TrialLabel.Text = ['   delay of ' num2str(delay,'%.1f') ' s'...
                                newline 'trial ' num2str(nTrials)];
                            drawnow
                            pause(delay-t)
                        else
                            app.TrialLabel.Text = ['It couldn''t achieve the time: ' num2str(t-delay) 's extra'];
                        end
                        real_angle = app.real_degrees(stimOutput(j));
                        
                        % Plot next grating
                        app.PreviewImage.ImageSource = image;
                        app.DirectionLabel.Text = [app.DirectionsLabel.Text(stimOutput(j)) newline...
                            num2str(real_angle) '°'];
                        drawnow
                        
                        for i = 1:movie_duration_frames
                            % Draw image
                            Screen('DrawTexture',app.window,tex(movie_frame_indices(i)));
                            Screen('Flip',app.window);
                            
                            if app.output_stims
                                % 0deg = 0.5V, 45deg = 1V, ..., 315deg = 4V
                                app.ni.outputSingleScan(real_angle/90+0.5);
                            end
                        end
                        
                        % Color gray
                        Screen('FillRect',app.window,app.gray);
                        Screen('Flip',app.window);
                        if app.output_stims
                            app.ni.outputSingleScan(0);
                        end
                        app.DirectionLabel.Text = '';
                        app.PreviewImage.ImageSource = repmat(0.5*ones(size(m)),1,1,3);
                        drawnow
                        nTrials = nTrials+1;
                        tic
                    end
                end
                assignin('base','last_sequence',stimSequence)
                app.RunButton.Text = 'Run';
                app.RunButton.Value = false;
                app.DirectionLabel.Text = '';
                app.TrialLabel.Text = '';
            else
                app.cancel = true;
                app.RunButton.Text = 'Stopping';
            end
        end

        % Value changed function: StimulationWindowSwitch
        function StimulationWindowSwitchValueChanged(app, event)
            value = app.StimulationWindowSwitch.Value;
            if strcmp(value,'On')
                % Skip test on Mac
                Screen('Preference', 'SkipSyncTests', app.skip_test);
                
                % OpenGL Psychtoolbox
                AssertOpenGL;
            
                % Get black and white color values
                white = WhiteIndex(app.screen_number);
                black = BlackIndex(app.screen_number);
                if white==1
                    white = 255;
                end
            
                % Make gray
                app.gray = round((white+black)/2);
                
                % Contrast 'app.increment'rement range for given white and gray values:
                app.increment = white-app.gray;
            
                % Open fullscreen gray window
                try
                    [app.window,rect] = Screen('OpenWindow',app.screen_number, app.gray);
                    app.half_width_w = round(rect(3)/2);
                    app.half_height_w = round(rect(4)/2);
                    UpdatePreview(app)
                    
                    % Change property values
                    app.WindowLamp.Color = [0,1,0];
                    app.RunButton.Enable = true;
                    app.SkipCheckBox.Enable = false;
                    app.MonitorDropDown.Enable = false;
                catch
                    % Close window:
                    Priority(0);
                    Screen('CloseAll')
                    sca;
                
                    % Change property values
                    app.WindowLamp.Color = [1,0,0];
                    app.StimulationWindowSwitch.Value = 'Off';
                    app.RunButton.Enable = false;
                    app.SkipCheckBox.Enable = true;
                    app.MonitorDropDown.Enable = true;
                end
            else
                % Close window:
                Priority(0);
                Screen('CloseAll')
                sca;
                
                % Change property values
                app.RunButton.Enable = false;
                app.SkipCheckBox.Enable = true;
                app.MonitorDropDown.Enable = true;
                app.WindowLamp.Color = [0.5,0.5,0.5];
            end
            
        end

        % Value changed function: DirectionsDropDown
        function DirectionsDropDownValueChanged(app, event)
            value = app.DirectionsDropDown.Value;
            app.DirectionsLabel.Text = value;
            switch value
                case '→↗↑↖←↙↓↘'
                    app.stim_degrees = [180 135 90 45 0 315 270 225];
                    app.real_degrees = [0 45 90 135 180 225 270 315];
                case '→↑←↓'
                    app.stim_degrees = [180 90 0 270];
                    app.real_degrees = [0 90 180 270];
                case '→'
                    app.stim_degrees = 180;
                    app.real_degrees = 0;
                case '↗'
                    app.stim_degrees = 135;
                    app.real_degrees = 45;
                case '↑'
                    app.stim_degrees = 90;
                    app.real_degrees = 90;
                case '↖'
                    app.stim_degrees = 45;
                    app.real_degrees = 135;
                case '←'
                    app.stim_degrees = 0;
                    app.real_degrees = 180;
                case '↙'
                    app.stim_degrees = 315;
                    app.real_degrees = 225;
                case '↓'
                    app.stim_degrees = 270;
                    app.real_degrees = 270;
                case '↘'
                    app.stim_degrees = 225;
                    app.real_degrees = 315;
            end
            app.num_stim = length(app.stim_degrees);
            ComputeFinalTime(app)
            
        end

        % Value changed function: SizeEditField
        function SizeEditFieldValueChanged(app, event)
            app.grating_pixels = app.SizeEditField.Value;
            UpdatePreview(app)
        end

        % Value changed function: FrequencyHzEditField
        function FrequencyHzEditFieldValueChanged(app, event)
            app.cycles_per_second = app.FrequencyHzEditField.Value;
        end

        % Value changed function: RandomCheckBox
        function RandomCheckBoxValueChanged(app, event)
            app.rand_stim = app.RandomCheckBox.Value;
        end

        % Value changed function: SinusoidalCheckBox
        function SinusoidalCheckBoxValueChanged(app, event)
            app.sinusoidal = app.SinusoidalCheckBox.Value;
            UpdatePreview(app)
        end

        % Value changed function: SkipCheckBox
        function SkipCheckBoxValueChanged(app, event)
            if app.SkipCheckBox.Value
                app.skip_test = 1;
                app.SkipCheckBox.FontColor = [1 0 0];
            else
                app.skip_test = 0;
                app.SkipCheckBox.FontColor = [0 0 0];
            end
        end

        % Value changed function: OutputDropDown
        function OutputDropDownValueChanged(app, event)
            value = app.OutputDropDown.Value;
            
            if strcmp(value,'None')
                app.output_stims = false;
                app.OutputDropDown.BackgroundColor = [0.96 0.96 0.96];
            else
                app.output_stims = true;
                device = value(1:4);
                channel = value(8:10);
                try
                    app.ni = daq.createSession('ni');
                    app.ni.addAnalogOutputChannel(device,channel,'Voltage');
                    app.ni.IsContinuous = true;
                    app.ni.outputSingleScan(0);
                    app.OutputDropDown.BackgroundColor = [0 1 0];
                catch
                    app.OutputDropDown.BackgroundColor = [1 0 0];
                end
            end
        end

        % Value changed function: StimulusEditField
        function StimulusEditFieldValueChanged(app, event)
            app.stim_duration = app.StimulusEditField.Value;
            ComputeFinalTime(app)
        end

        % Value changed function: GrayEditField
        function GrayEditFieldValueChanged(app, event)
            app.gray_duration = app.GrayEditField.Value;
            app.GrayRndSpinner.Limits = [0 app.gray_duration-1];
            ComputeFinalTime(app)
            
        end

        % Value changed function: RepetitionsEditField
        function RepetitionsEditFieldValueChanged(app, event)
            app.repetitions = app.RepetitionsEditField.Value;
            ComputeFinalTime(app)
        end

        % Close request function: GratingsUIFigure
        function GratingsUIFigureCloseRequest(app, event)
            try
                Priority(0);
                Screen('CloseAll')
                sca;
                delete(app)
            catch
                delete(app)
            end
            
        end

        % Value changed function: MonitorDropDown
        function MonitorDropDownValueChanged(app, event)
            app.screen_number = str2double(app.MonitorDropDown.Value);
        end

        % Value changed function: GrayRndSpinner
        function GrayRndSpinnerValueChanged(app, event)
            app.gray_rnd = app.GrayRndSpinner.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GratingsUIFigure = uifigure('Visible', 'off');
            app.GratingsUIFigure.Name = 'Gratings by Jesus Perez-Ortega';
            app.GratingsUIFigure.CloseRequestFcn = createCallbackFcn(app, @GratingsUIFigureCloseRequest, true);
            app.ActivatestimulationwindowPanel = uipanel(app.GratingsUIFigure);
            app.ActivatestimulationwindowPanel.Title = 'Activate stimulation window';
            app.StimulationWindowSwitch = uiswitch(app.ActivatestimulationwindowPanel, 'rocker');
            app.StimulationWindowSwitch.ValueChangedFcn = createCallbackFcn(app, @StimulationWindowSwitchValueChanged, true);
            app.WindowLamp = uilamp(app.ActivatestimulationwindowPanel);
            app.SkipCheckBox = uicheckbox(app.ActivatestimulationwindowPanel);
            app.SkipCheckBox.ValueChangedFcn = createCallbackFcn(app, @SkipCheckBoxValueChanged, true);
            app.SkipCheckBox.Tooltip = {'Windows users: If skip is selected this will seriously impair proper stimulus presentation and stimulus presentation timing!'; 'Mac users: must be skip in order to run the code.'};
            app.SkipCheckBox.Text = 'Skip synchronization test ⚠';
            app.SelectscreenLabel = uilabel(app.ActivatestimulationwindowPanel);
            app.SelectscreenLabel.Text = 'Select screen:';
            app.MonitorDropDown = uidropdown(app.ActivatestimulationwindowPanel);
            app.MonitorDropDown.Items = {'1'};
            app.MonitorDropDown.ValueChangedFcn = createCallbackFcn(app, @MonitorDropDownValueChanged, true);
            app.MonitorDropDown.Value = '1';
            app.ConfigurationPanel = uipanel(app.GratingsUIFigure);
            app.ConfigurationPanel.Title = 'Configuration';
            app.DirectionsDropDownLabel = uilabel(app.ConfigurationPanel);
            app.DirectionsDropDownLabel.Text = 'Directions:';
            app.DirectionsDropDown = uidropdown(app.ConfigurationPanel);
            app.DirectionsDropDown.Items = {'→↗↑↖←↙↓↘', '→↑←↓', '→', '↗', '↑', '↖', '←', '↙', '↓', '↘'};
            app.DirectionsDropDown.ValueChangedFcn = createCallbackFcn(app, @DirectionsDropDownValueChanged, true);
            app.DirectionsDropDown.Value = '→↗↑↖←↙↓↘';
            app.RandomCheckBox = uicheckbox(app.ConfigurationPanel);
            app.RandomCheckBox.ValueChangedFcn = createCallbackFcn(app, @RandomCheckBoxValueChanged, true);
            app.RandomCheckBox.Text = 'Random sequence';
            app.RandomCheckBox.Value = true;
            app.GratingssizepixelsLabel = uilabel(app.ConfigurationPanel);
            app.GratingssizepixelsLabel.Text = 'Gratings size (pixels):';
            app.SizeEditField = uieditfield(app.ConfigurationPanel, 'numeric');
            app.SizeEditField.Limits = [5 1000];
            app.SizeEditField.RoundFractionalValues = 'on';
            app.SizeEditField.ValueChangedFcn = createCallbackFcn(app, @SizeEditFieldValueChanged, true);
            app.SizeEditField.Value = 200;
            app.FrequencyHzEditFieldLabel = uilabel(app.ConfigurationPanel);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency (Hz):';
            app.FrequencyHzEditField = uieditfield(app.ConfigurationPanel, 'numeric');
            app.FrequencyHzEditField.Limits = [0.1 100];
            app.FrequencyHzEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyHzEditFieldValueChanged, true);
            app.FrequencyHzEditField.Value = 5;
            app.SinusoidalCheckBox = uicheckbox(app.ConfigurationPanel);
            app.SinusoidalCheckBox.ValueChangedFcn = createCallbackFcn(app, @SinusoidalCheckBoxValueChanged, true);
            app.SinusoidalCheckBox.Text = 'Sinusoidal';
            app.SinusoidalCheckBox.Value = true;
            app.OutputvoltageDropDownLabel = uilabel(app.ConfigurationPanel);
            app.OutputvoltageDropDownLabel.Text = 'Output voltage:';
            app.OutputDropDown = uidropdown(app.ConfigurationPanel);
            app.OutputDropDown.Items = {'None', 'Dev1 - ao0', 'Dev1 - ao1', 'Dev1 - ao2', 'Dev1 - ao3', 'Dev2 - ao0', 'Dev2 - ao1', 'Dev2 - ao2', 'Dev2 - ao3'};
            app.OutputDropDown.ValueChangedFcn = createCallbackFcn(app, @OutputDropDownValueChanged, true);
            app.OutputDropDown.Value = 'None';
            app.DurationPanel = uipanel(app.GratingsUIFigure);
            app.DurationPanel.Title = 'Duration';
            app.StimulussLabel = uilabel(app.DurationPanel);
            app.StimulussLabel.Text = 'Stimulus (s):';
            app.StimulusEditField = uieditfield(app.DurationPanel, 'numeric');
            app.StimulusEditField.Limits = [0.02 100];
            app.StimulusEditField.ValueChangedFcn = createCallbackFcn(app, @StimulusEditFieldValueChanged, true);
            app.StimulusEditField.Value = 2;
            app.GraysLabel = uilabel(app.DurationPanel);
            app.GraysLabel.Text = 'Gray (s):';
            app.GrayEditField = uieditfield(app.DurationPanel, 'numeric');
            app.GrayEditField.Limits = [0.02 100];
            app.GrayEditField.ValueChangedFcn = createCallbackFcn(app, @GrayEditFieldValueChanged, true);
            app.GrayEditField.Value = 3;
            app.RepetitionsEditFieldLabel = uilabel(app.DurationPanel);
            app.RepetitionsEditFieldLabel.Text = 'Repetitions:';
            app.RepetitionsEditField = uieditfield(app.DurationPanel, 'numeric');
            app.RepetitionsEditField.Limits = [1 1000];
            app.RepetitionsEditField.RoundFractionalValues = 'on';
            app.RepetitionsEditField.ValueChangedFcn = createCallbackFcn(app, @RepetitionsEditFieldValueChanged, true);
            app.RepetitionsEditField.Value = 10;
            app.Label = uilabel(app.DurationPanel);
            app.Label.Text = '±';
            app.GrayRndSpinner = uispinner(app.DurationPanel);
            app.GrayRndSpinner.Step = 0.1;
            app.GrayRndSpinner.Limits = [0 2];
            app.GrayRndSpinner.ValueChangedFcn = createCallbackFcn(app, @GrayRndSpinnerValueChanged, true);
            app.StimuliPanel = uipanel(app.GratingsUIFigure);
            app.StimuliPanel.Title = 'Stimuli';
            app.DirectionsLabel = uilabel(app.StimuliPanel);
            app.DirectionsLabel.Text = '→↗↑↖←↙↓↘';
            app.TimeLabel = uilabel(app.StimuliPanel);
            app.TimeLabel.Text = 'Total time:';
            app.PreviewImage = uiimage(app.StimuliPanel);
            app.PreviewImage.Tooltip = {''};
            app.RunButton = uibutton(app.StimuliPanel, 'state');
            app.RunButton.ValueChangedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Enable = 'off';
            app.RunButton.Text = 'Run';
            app.DirectionLabel = uilabel(app.StimuliPanel);
            app.DirectionLabel.Text = '';
            app.TrialLabel = uilabel(app.StimuliPanel);
            app.TrialLabel.Text = '';
            % Show the figure after all components are created
            app.GratingsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Gratings

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GratingsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GratingsUIFigure)
        end
    end
end